classdef Proyecto_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        Lamp                  matlab.ui.control.Lamp
        Button_2              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        VolumeKnob            matlab.ui.control.Knob
        VolumeKnobLabel       matlab.ui.control.Label
        Button                matlab.ui.control.Button
        MusicRecognizerLabel  matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        MainTab               matlab.ui.container.Tab
        LOADINGLabel          matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        x;
        y;
        t;
        Vol;
        Canal_Reproduccion;
        playerOriginal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Lamp.Color  = [1 0 0];
            app.Lamp.Enable = 'on';
            app.x = [0];
            recObj = [0];
            Tiempo = 10;
            FrecuenciaGrabacion = 44100;
            recObj = audiorecorder(FrecuenciaGrabacion,16,2,1);
            sample = FrecuenciaGrabacion * Tiempo;
            record(recObj);
            pause(0.1);
            for i = 1 : Tiempo*10
                if length(app.x) < sample
                    app.x = getaudiodata(recObj);
                    app.t = linspace(0,length(app.x)/FrecuenciaGrabacion,length(app.x));
                    plot(app.UIAxes,app.t,app.x);
                    pause(0.1);
                end
            end
            stop(recObj);

            FrecuenciaMuestreo = 8000;

            app.x = resample(app.x,FrecuenciaMuestreo, FrecuenciaGrabacion);

            a = [1 -2.474416174978162796804781464743427932262 2.811006311911582233875606107176281511784 -1.703772240915468305644253632635809481144 0.544432694888534185473361048934748396277 -0.072315669102958543557058135320403380319];
            b = [0.268935721618647038955174366492428816855 -1.344678608093235139264720601204317063093 2.689357216186470722618651052471250295639 -2.689357216186470722618651052471250295639 1.344678608093235139264720601204317063093 -0.268935721618647038955174366492428816855];
            app.x = filter(b,a,app.x);
            

            app.Lamp.Enable = 'off';
            app.Lamp.Color  = [0 1 0];
            
            Archivo = fopen('BasedeDatosCanciones.txt');
            Path = textscan(Archivo, '%s %s', 'delimiter', ';');
            

            Numcanciones = length(Path{1});
            Max = 0;
            Fila = 0;
            Max_song = 0;

            for i = 1 : Numcanciones
                currentPath = string(fullfile(pwd,Path{1}{i}));
                disp(currentPath);
                [Cancion, Fs] = audioread(currentPath);
                Cancion = resample(Cancion, FrecuenciaMuestreo, Fs);
                CancionMono = Cancion(:,1);
                Correlacion = xcorr(CancionMono,app.x(:,1));
                Max_song = max(Correlacion);
                disp(Max_song);
                if (Max_song >= Max)
                    Max = Max_song;
                    Fila = i;
                    app.y = CancionMono;
                end
            end

            disp(Max);
            app.Image.ImageSource = fullfile(pwd,Path{2}{Fila});
            app.Image.Visible = 'on';


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            FrecuenciaMuestreo = 8000;
            app.Vol =  app.VolumeKnob.Value / 100;
            app.Canal_Reproduccion = 1;
            app.playerOriginal = audioplayer(app.Vol*app.x,FrecuenciaMuestreo);
            stop(app.playerOriginal);
            play(app.playerOriginal);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            FrecuenciaMuestreo = 8000;
            app.Vol =  app.VolumeKnob.Value / 100;
            app.Canal_Reproduccion = 2;
            app.playerOriginal = audioplayer(app.Vol*app.y,FrecuenciaMuestreo);
            stop(app.playerOriginal);
            play(app.playerOriginal);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isplaying(app.playerOriginal)
                stop(app.playerOriginal)
            end
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            value = app.VolumeKnob.Value;
            app.Vol = event.Value / 100;
            FrecuenciaMuestreo = 8000;
            if isplaying(app.playerOriginal)
                    pause(app.playerOriginal);
                    pause(1)
                    NewStart = get(app.playerOriginal,'CurrentSample');
                    stop(app.playerOriginal);
                if app.Canal_Reproduccion == 1
                    ContinueSignal=app.x(NewStart:end,:);
                    app.playerOriginal=audioplayer(ContinueSignal*app.Vol,FrecuenciaMuestreo);
                    play(app.playerOriginal);
                elseif app.Canal_Reproduccion == 2
                    ContinueSignal=app.y(NewStart:end,:);
                    app.playerOriginal=audioplayer(ContinueSignal*app.Vol,FrecuenciaMuestreo);
                    play(app.playerOriginal);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MusicRecognizerLabel = uilabel(app.LeftPanel);
            app.MusicRecognizerLabel.Text = 'Music Recognizer';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'record.png';
            app.Button.Text = '';
            app.VolumeKnobLabel = uilabel(app.LeftPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.LeftPanel, 'continuous');
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Value = 80;
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'images.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '2468884.png';
            app.Button_4.Text = '';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = '362-3628296_voice-memos-icon-iphone-voice-recorder-icon-clipart.png';
            app.Button_2.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Audio record')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.MainTab);
            app.Image.Visible = 'off';
            app.LOADINGLabel = uilabel(app.MainTab);
            app.LOADINGLabel.Visible = 'off';
            app.LOADINGLabel.Text = 'LOADING ...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end